classdef DateDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        YourNameEditFieldLabel       matlab.ui.control.Label
        YourNameEditField            matlab.ui.control.EditField
        SelectedDateDatePickerLabel  matlab.ui.control.Label
        SelectedDateDatePicker       matlab.ui.control.DatePicker
        DateFormatDropDownLabel      matlab.ui.control.Label
        DateFormatDropDown           matlab.ui.control.DropDown
        ShowMessageButton            matlab.ui.control.Button
        WelcometotheDateDisplayAppLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowMessageButton
        function ShowMessageButtonPushed(app, event)
            name = app.YourNameEditField.Value;
            formatOut = app.DateFormatDropDown.Value;
            selDate = app.SelectedDateDatePicker.Value;
            if strcmp(formatOut, 'Default')
                formatOut = 'dd-mmm-yyyy'
            end
            date = datestr(selDate, formatOut);
            msg = ['Hello ', name, ' it''s ', date, '!'];
            app.Label.Text = msg;
        end

        % Value changed function: DateFormatDropDown
        function DateFormatDropDownValueChanged(app, event)
            value = app.DateFormatDropDown.Value;
            if strcmp(value, 'Default')
                value = 'dd-mmm-yyyy'
            end
            app.SelectedDateDatePicker.DisplayFormat = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YourNameEditFieldLabel = uilabel(app.UIFigure);
            app.YourNameEditFieldLabel.Text = 'Your Name';
            app.YourNameEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedDateDatePickerLabel = uilabel(app.UIFigure);
            app.SelectedDateDatePickerLabel.Text = 'Selected Date';
            app.SelectedDateDatePicker = uidatepicker(app.UIFigure);
            app.SelectedDateDatePicker.DisplayFormat = 'dd-MMM-yyyy';
            app.SelectedDateDatePicker.Value = datetime([2021 1 12]);
            app.DateFormatDropDownLabel = uilabel(app.UIFigure);
            app.DateFormatDropDownLabel.Text = 'Date Format';
            app.DateFormatDropDown = uidropdown(app.UIFigure);
            app.DateFormatDropDown.Items = {'Default', 'yyyy-mmm-dd', 'yyyy-mm-dd', 'dd/mmm/yyyy', 'dd-mm-yyyy'};
            app.DateFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @DateFormatDropDownValueChanged, true);
            app.DateFormatDropDown.Value = 'Default';
            app.ShowMessageButton = uibutton(app.UIFigure, 'push');
            app.ShowMessageButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMessageButtonPushed, true);
            app.ShowMessageButton.Text = 'Show Message';
            app.WelcometotheDateDisplayAppLabel = uilabel(app.UIFigure);
            app.WelcometotheDateDisplayAppLabel.Text = 'Welcome to the Date Display App!';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DateDisplay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end